classdef logowanie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZalogujsidosystemuUIFigure  matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        ZalogujButton               matlab.ui.control.Button
        LogowanieLabel              matlab.ui.control.Label
        LoginEditField              matlab.ui.control.EditField
        LoginEditFieldLabel         matlab.ui.control.Label
        HasoEditField               matlab.ui.control.EditField
        HasoEditFieldLabel          matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
           user_password=''; 
           prev_password_length=0; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZalogujButton
        function ZalogujButtonPushed(app, event)
           IsLogged = Login_QuestionHTTP(app.LoginEditField.Value,app.user_password);
           if(IsLogged == true)
                okno_glowne
                app.ZalogujsidosystemuUIFigure.Visible='off';
           else
               f = msgbox(IsLogged,'error');
           end
        end

        % Value changing function: HasoEditField
        function HasoEditFieldValueChanging(app, event)
            val = event.Value;
            current_pass_length=length(val); 
            app.prev_password_length=length(app.user_password);

            if isempty(val)
                app.user_password='';   
                app.prev_password_length=0;  
                app.HasoEditField.Value='';  
                
            elseif(current_pass_length<app.prev_password_length)
                app.user_password = app.user_password(1:current_pass_length);
                app.HasoEditField.Value = repelem('*', current_pass_length); 
            else
                add_string=erase(val, repelem('*', length(app.user_password)));
                app.user_password=append(app.user_password,add_string);
                app.HasoEditField.Value = repelem('*', length(app.user_password));
            end   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZalogujsidosystemuUIFigure = uifigure('Visible', 'off');
            app.ZalogujsidosystemuUIFigure.Name = 'Zaloguj się do systemu';
            app.ZalogujsidosystemuUIFigure.Icon = 'account.png';
            app.Panel = uipanel(app.ZalogujsidosystemuUIFigure);
            app.ZalogujButton = uibutton(app.ZalogujsidosystemuUIFigure, 'push');
            app.ZalogujButton.ButtonPushedFcn = createCallbackFcn(app, @ZalogujButtonPushed, true);
            app.ZalogujButton.Text = {'Zaloguj'; ''};
            app.LogowanieLabel = uilabel(app.ZalogujsidosystemuUIFigure);
            app.LogowanieLabel.Text = 'Logowanie';
            app.LoginEditField = uieditfield(app.ZalogujsidosystemuUIFigure, 'text');
            app.LoginEditFieldLabel = uilabel(app.ZalogujsidosystemuUIFigure);
            app.LoginEditFieldLabel.Text = 'Login';
            app.HasoEditField = uieditfield(app.ZalogujsidosystemuUIFigure, 'text');
            app.HasoEditField.ValueChangingFcn = createCallbackFcn(app, @HasoEditFieldValueChanging, true);
            app.HasoEditFieldLabel = uilabel(app.ZalogujsidosystemuUIFigure);
            app.HasoEditFieldLabel.Text = 'Hasło';
            app.Image = uiimage(app.ZalogujsidosystemuUIFigure);
            app.Image.ImageSource = 'account.png';
            % Show the figure after all components are created
            app.ZalogujsidosystemuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logowanie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZalogujsidosystemuUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZalogujsidosystemuUIFigure)
        end
    end
end