classdef okno_glowne < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ListapacjentwListBoxLabel   matlab.ui.control.Label
        ListapacjentwListBox        matlab.ui.control.ListBox
        BadaniaDropDownLabel        matlab.ui.control.Label
        BadaniaDropDown             matlab.ui.control.DropDown
        SegmentujButton             matlab.ui.control.Button
        KlasyfikujButton            matlab.ui.control.Button
        WynikklasyfikacjiEditFieldLabel  matlab.ui.control.Label
        WynikklasyfikacjiEditField  matlab.ui.control.EditField
        agodny2Zoliwy3BrakLabel     matlab.ui.control.Label
        FiltrujButton               matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        KontrastSlider_2Label       matlab.ui.control.Label
        KontrastSlider_2            matlab.ui.control.Slider
        JasnoSlider_2Label          matlab.ui.control.Label
        Right                       matlab.ui.control.Image
        Left                        matlab.ui.control.Image
        JasnoSlider_2               matlab.ui.control.Slider
        Number_image                matlab.ui.control.NumericEditField
        SerieDropDownLabel          matlab.ui.control.Label
        SerieDropDown               matlab.ui.control.DropDown
        SerieDropDown_2Label        matlab.ui.control.Label
        SerieDropDown_2             matlab.ui.control.DropDown
        KontrastSlider_2Label_2     matlab.ui.control.Label
        KontrastSlider_4            matlab.ui.control.Slider
        JasnoSlider_3Label          matlab.ui.control.Label
        JasnoSlider_3               matlab.ui.control.Slider
        ZapiszButton                matlab.ui.control.Button
        OpisbadaniaLabel            matlab.ui.control.Label
        OpisbadaniaTextArea         matlab.ui.control.TextArea
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
       I,
       I_drugi,
       index,
       DicomInfo,
       Autor,
       image1,
       image2,
       bright2ValueTemp,
       maximumLeftBase,
       minimumRightBase,
       maximumRightBase,
       minimumLeftBase,
       extracted
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            patientsHTTP = Find_QuestionHTTP();
             for k=1:length(patientsHTTP)
                patients(k,1)=convertCharsToStrings(patientsHTTP(k,1));
             end
            app.ListapacjentwListBox.Items=patients;
          app.SerieDropDown.Enable='off';
          app.SerieDropDown_2.Enable='off';
          app.BadaniaDropDown.Enable='off';
          app.OpisbadaniaTextArea.Enable='off';
app.ZapiszButton.Enable='off';
app.FiltrujButton.Enable='off';
app.SegmentujButton.Enable='off';
app.KlasyfikujButton.Enable='off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {763, 763, 763};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {763, 763};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: ListapacjentwListBox
        function ListapacjentwListBoxValueChanged(app, event)
            app.DicomInfo.id = event.Value;
            StudyHTTP = FindStudy_QuestionHTTP(app.DicomInfo);
            Study(1,1) = "Wybierz Badanie";
         for k=1:length(StudyHTTP)
                Study(k+1,1)=convertCharsToStrings(StudyHTTP(k,1));
         end         
         
            app.Number_image.Value = 0;
           app.BadaniaDropDown.Items = ["Wybierz Badanie", ""];
           app.SerieDropDown.Items = ["", ""];
           app.SerieDropDown_2.Items = ["", ""];
           app.BadaniaDropDown.Items=arrayfun(@num2str,Study,'un',0);      
                     app.SerieDropDown.Enable='off';
          app.SerieDropDown_2.Enable='off';
app.BadaniaDropDown.Enable='on';
app.OpisbadaniaTextArea.Enable='off';
app.ZapiszButton.Enable='off';
app.FiltrujButton.Enable='off';
app.SegmentujButton.Enable='off';
app.KlasyfikujButton.Enable='off';
          app.WynikklasyfikacjiEditField.Value="";
          cla(app.UIAxes) 
cla(app.UIAxes_2) 
app.OpisbadaniaTextArea.Value="";
        end

        % Callback function
        function WybierzseriedanychdlaoknapierwszegoListBoxValueChanged(app, event)
            series= evalin('base','series')
            value = app.WybierzseriedanychdlaoknapierwszegoListBox.Value;
            app.I=series(app.index).s.serie{str2double(value),1};
            sz=size(app.I());
            max_image=sz(3);
            app.Number_image.Limits=[1, max_image];
            number = app.Number_image.Value;
            imshow(app.I(:,:,number),[],'parent',app.UIAxes)
            app.OpisbadaniaTextArea.Enable='on';
app.ZapiszButton.Enable='on';
            
        end

        % Value changed function: Number_image
        function Number_imageValueChanged(app, event)
            number = app.Number_image.Value;
            imshow(app.I(:,:,number),[],'parent',app.UIAxes)
            imshow(app.I_drugi(:,:,number),[],'parent',app.UIAxes)
        end

        % Image clicked function: Right
        function RightImageClicked(app, event)
            limits=length(app.SerieDropDown.Items);
            idx = find(strcmpi(app.SerieDropDown.Items, app.DicomInfo.seriesInstanceUID));
            if(idx < limits)
                
           app.DicomInfo.seriesInstanceUID = app.SerieDropDown.Items{idx+1};
           app.Number_image.Value = idx;
           app.SerieDropDown.Value = app.SerieDropDown.Items{idx+1};
           app.DicomInfo.sopInstanceUID = convertCharsToStrings(FindInstance_QuestionHTTP(app.DicomInfo));
           ZdjecieHTTP = TakeImage_QuestionHTTP(app.DicomInfo);
           
          
            app.image1=DecodeImage(ZdjecieHTTP);
            
            app.image2=app.image1;
            app.image1=im2gray(app.image1);
            imshow(app.image1,[],'parent',app.UIAxes) 
            imshow(app.image2,[],'parent',app.UIAxes_2) 
                
            else
            app.DicomInfo.seriesInstanceUID = app.SerieDropDown.Items{2};
            app.SerieDropDown.Value = app.SerieDropDown.Items{2};
            app.DicomInfo.sopInstanceUID = convertCharsToStrings(FindInstance_QuestionHTTP(app.DicomInfo));
            ZdjecieHTTP = TakeImage_QuestionHTTP(app.DicomInfo);
           
            app.image1=DecodeImage(ZdjecieHTTP);
            
            app.image2=app.image1;
            app.image1=im2gray(app.image1);
            imshow(app.image1,[],'parent',app.UIAxes) 
            imshow(app.image2,[],'parent',app.UIAxes_2) 
            end
        end

        % Image clicked function: Left
        function LeftImageClicked(app, event)
            
            idx = find(strcmpi(app.SerieDropDown.Items, app.DicomInfo.seriesInstanceUID));
            if(idx > 2)
                
           app.DicomInfo.seriesInstanceUID = app.SerieDropDown.Items{idx-1};
           app.SerieDropDown.Value = app.SerieDropDown.Items{idx-1};
           app.DicomInfo.sopInstanceUID = convertCharsToStrings(FindInstance_QuestionHTTP(app.DicomInfo));
           ZdjecieHTTP = TakeImage_QuestionHTTP(app.DicomInfo);
           
          
            app.image1=DecodeImage(ZdjecieHTTP);
            
            app.image2=app.image1;
            app.image1=im2gray(app.image1);
            imshow(app.image1,[],'parent',app.UIAxes) 
            imshow(app.image2,[],'parent',app.UIAxes_2) 
                
            else
            last=length(app.SerieDropDown.Items);
            app.DicomInfo.seriesInstanceUID = app.SerieDropDown.Items{last};
            app.SerieDropDown.Value = app.SerieDropDown.Items{last};
            app.DicomInfo.sopInstanceUID = convertCharsToStrings(FindInstance_QuestionHTTP(app.DicomInfo));
            ZdjecieHTTP = TakeImage_QuestionHTTP(app.DicomInfo);
           
            app.image1=DecodeImage(ZdjecieHTTP);
            
            app.image2=app.image1;
            app.image1=im2gray(app.image1);
            imshow(app.image1,[],'parent',app.UIAxes) 
            imshow(app.image2,[],'parent',app.UIAxes_2) 
            end
        end

        % Callback function
        function WybierzseriedanychdlaoknadrugiegoListBoxValueChanged(app, event)

            series= evalin('base','series')
            value = app.WybierzseriedanychdlaoknadrugiegoListBox.Value;
            app.I_drugi=series(app.index).s.serie{str2double(value),1};
            sz=size(app.I_drugi());
            max_image=sz(3);
            app.Number_image.Limits=[1, max_image];
            number = app.Number_image.Value;
            imshow(app.I_drugi(:,:,number),[],'parent',app.UIAxes_2)
        end

        % Callback function
        function InstancjaDropDownOpening(app, event)
           
        end

        % Callback function
        function SerieDropDownOpening(app, event)
           
        end

        % Value changed function: BadaniaDropDown
        function BadaniaDropDownValueChanged(app, event)
            value = event.Source.Value;
            app.DicomInfo.studyInstanceUID = app.BadaniaDropDown.Value;
            SeriesHTTP = FindSeries_QuestionHTTP(app.DicomInfo);
            Series(1,1) = "Wybierz Serie";
            
            app.Number_image.Value = 0;
         for k=1:length(SeriesHTTP)
                Series(k+1,1)=convertCharsToStrings(SeriesHTTP(k,1));
         end           
           app.SerieDropDown.Items = ["Wybierz Serie", ""];           
           app.SerieDropDown_2.Items = ["Wybierz Serie", ""];
           app.SerieDropDown.Items=arrayfun(@num2str,Series,'un',0);         
          
           app.SerieDropDown_2.Items=arrayfun(@num2str,Series,'un',0);  
          app.WynikklasyfikacjiEditField.Value="";
          app.SerieDropDown.Enable='on';
          app.SerieDropDown_2.Enable='on';
          app.OpisbadaniaTextArea.Enable='off';
app.ZapiszButton.Enable='off';
app.FiltrujButton.Enable='off';
app.SegmentujButton.Enable='off';
app.KlasyfikujButton.Enable='off';
cla(app.UIAxes) 
cla(app.UIAxes_2) 
app.OpisbadaniaTextArea.Value="";
        end

        % Value changed function: SerieDropDown
        function SerieDropDownValueChanged(app, event)
           app.DicomInfo.seriesInstanceUID = event.Value;
           app.DicomInfo.sopInstanceUID = convertCharsToStrings(FindInstance_QuestionHTTP(app.DicomInfo));
           ZdjecieHTTP = TakeImage_QuestionHTTP(app.DicomInfo);
           
            idx = find(strcmpi(app.SerieDropDown.Items, app.DicomInfo.seriesInstanceUID));
            app.Number_image.Value = idx-1;
          
            app.image1=DecodeImage(ZdjecieHTTP);
            app.image1=im2gray(app.image1);
            
            
             minimumRight = min(min(app.image1(:,:,1)));
            maximumRight = max(max(app.image1(:,:,1)));
           app.minimumRightBase = minimumRight;
            app.maximumRightBase = maximumRight;
            
            
            minimumLeft = min(min(app.image1(:,:,1)));
            maximumLeft = max(max(app.image1(:,:,1)));
            app.maximumLeftBase = maximumLeft;
            app.minimumLeftBase = minimumLeft;
            
            
            imshow(app.image1,[],'parent',app.UIAxes)        
            
            name = string(app.DicomInfo.id)+ string(app.DicomInfo.sopInstanceUID);
            textInfo = TakeText_QuestionHTTP(name);
            app.OpisbadaniaTextArea.Value = textInfo;
           app.OpisbadaniaTextArea.Enable='on';
app.ZapiszButton.Enable='on';
app.FiltrujButton.Enable='on';
app.SegmentujButton.Enable='off';
app.KlasyfikujButton.Enable='off';
        end

        % Drop down opening function: BadaniaDropDown
        function BadaniaDropDownOpening(app, event)
            
        end

        % Value changed function: SerieDropDown_2
        function SerieDropDown_2ValueChanged(app, event)
           app.DicomInfo.seriesInstanceUID = event.Value;
           app.DicomInfo.sopInstanceUID = convertCharsToStrings(FindInstance_QuestionHTTP(app.DicomInfo));
           ZdjecieHTTP = TakeImage_QuestionHTTP(app.DicomInfo);
           
         
            app.image2=DecodeImage(ZdjecieHTTP);
            
           
            imshow(app.image2,[],'parent',app.UIAxes_2)        
            
        end

        % Button pushed function: SegmentujButton
        function SegmentujButtonPushed(app, event)
            app.image1 = imresize(app.image1,[256 256]); 
            app.image1 = SegmentationImage2(app.image1);
            imshow(app.image1,[],'parent',app.UIAxes)   
            app.FiltrujButton.Enable='on';
app.SegmentujButton.Enable='on';
app.KlasyfikujButton.Enable='on';
        end

        % Button pushed function: KlasyfikujButton
        function KlasyfikujButtonPushed(app, event)
            Img = app.image1;
            Img = imresize(Img,[256 256]); 
            ac = Classifier2(Img);
            app.WynikklasyfikacjiEditField.Value=num2str(ac); 
        end

        % Button pushed function: FiltrujButton
        function FiltrujButtonPushed(app, event)

            %app.image1=app.image1>800;
            %app.image1=uint8(app.image1);
            app.image1 = AdaptiveMedianFilter2(app.image1);
            imshow(app.image1,[],'parent',app.UIAxes)   
            app.FiltrujButton.Enable='on';
app.SegmentujButton.Enable='on';
app.KlasyfikujButton.Enable='off';
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
 f = waitbar(0,'Zapisywanie do pliku...');
pause(.5)

            tekst =  (app.OpisbadaniaTextArea.Value);
            w=size(tekst,1);
            k=size(tekst,2);
            nazwa=app.SerieDropDown.Value;
            nazwa = char(nazwa)+".txt";
            fileID = fopen(nazwa,'w');
            for i=1:w
              fprintf (fileID,tekst{i,:});
                  fprintf(fileID,'\n') ;
            end

            fclose(fileID);
waitbar(1,f,'Zapisano');
pause(1)

close(f)
app.Autor = "Admin";
     name =  string(app.DicomInfo.id)+ string(app.DicomInfo.sopInstanceUID); 
     str = convertCharsToStrings(tekst)';
     if(str(end)== "")
         
         str(end) = [];
     end
     str(str=="") = "123#$!";
     str(str==" ") = "123#$!";
    
     str = strjoin(str');
SaveToFile_QuestionHTTP(str + string("[Autor:"+app.Autor+"]"), name)


        end

        % Value changing function: JasnoSlider_3
        function JasnoSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.minimumRightBase = uint16(app.minimumRightBase);
            app.maximumRightBase = uint16(app.maximumRightBase);
            minimumRight = app.minimumRightBase - changingValue * 215;
            maximumRight = app.maximumRightBase - changingValue * 215;
            if(maximumRight - minimumRight >= 0)              
                imshow(app.image1,[minimumRight,maximumRight],'parent',app.UIAxes)  
            end
            app.bright2ValueTemp = value;
        end

        % Value changing function: JasnoSlider_2
        function JasnoSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            minimumRight = app.minimumRightBase - changingValue * 215;
            maximumRight = app.maximumRightBase - changingValue * 215;
            if(maximumRight - minimumRight >= 0)              
                imshow(app.image1,[minimumRight,maximumRight],'parent',app.UIAxes_2)  
            end
        end

        % Value changing function: KontrastSlider_4
        function KontrastSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.minimumRightBase = uint16(app.minimumRightBase);
            app.maximumRightBase = uint16(app.maximumRightBase);
            minimumRight = app.minimumRightBase - changingValue * 900;
            maximumRight = app.maximumRightBase + changingValue * 900;
            if(maximumRight - minimumRight > 0)              
                 imshow(app.image1,[minimumRight,maximumRight],'parent',app.UIAxes)  
            end
        end

        % Value changing function: KontrastSlider_2
        function KontrastSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.minimumRightBase = uint16(app.minimumRightBase);
            app.maximumRightBase = uint16(app.maximumRightBase);
            minimumRight = app.minimumRightBase - changingValue * 900;
            maximumRight = app.maximumRightBase + changingValue * 900;
            if(maximumRight - minimumRight > 0)              
                 imshow(app.image1,[minimumRight,maximumRight],'parent',app.UIAxes_2)  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure = uifigure('Visible', 'off');
            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.Name = 'System komputerowego wspomagania diagnostyki medycznej';
            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.Icon = 'account.png';
            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ListapacjentwListBoxLabel = uilabel(app.LeftPanel);
            app.ListapacjentwListBoxLabel.Text = 'Lista pacjentów';
            app.ListapacjentwListBox = uilistbox(app.LeftPanel);
            app.ListapacjentwListBox.Items = {};
            app.ListapacjentwListBox.ValueChangedFcn = createCallbackFcn(app, @ListapacjentwListBoxValueChanged, true);
            app.ListapacjentwListBox.Value = {};
            app.BadaniaDropDownLabel = uilabel(app.LeftPanel);
            app.BadaniaDropDownLabel.Text = 'Badania';
            app.BadaniaDropDown = uidropdown(app.LeftPanel);
            app.BadaniaDropDown.Items = {};
            app.BadaniaDropDown.DropDownOpeningFcn = createCallbackFcn(app, @BadaniaDropDownOpening, true);
            app.BadaniaDropDown.ValueChangedFcn = createCallbackFcn(app, @BadaniaDropDownValueChanged, true);
            app.BadaniaDropDown.Value = {};
            app.SegmentujButton = uibutton(app.LeftPanel, 'push');
            app.SegmentujButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentujButtonPushed, true);
            app.SegmentujButton.Text = 'Segmentuj';
            app.KlasyfikujButton = uibutton(app.LeftPanel, 'push');
            app.KlasyfikujButton.ButtonPushedFcn = createCallbackFcn(app, @KlasyfikujButtonPushed, true);
            app.KlasyfikujButton.Text = 'Klasyfikuj';
            app.WynikklasyfikacjiEditFieldLabel = uilabel(app.LeftPanel);
            app.WynikklasyfikacjiEditFieldLabel.Text = 'Wynik klasyfikacji';
            app.WynikklasyfikacjiEditField = uieditfield(app.LeftPanel, 'text');
            app.WynikklasyfikacjiEditField.Editable = 'off';
            app.agodny2Zoliwy3BrakLabel = uilabel(app.LeftPanel);
            app.agodny2Zoliwy3BrakLabel.Text = {'1. Łagodny'; '2. Złośliwy'; '3. Brak'};
            app.FiltrujButton = uibutton(app.LeftPanel, 'push');
            app.FiltrujButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrujButtonPushed, true);
            app.FiltrujButton.Text = 'Filtruj';
            app.CenterPanel = uipanel(app.GridLayout);
            app.KontrastSlider_2Label = uilabel(app.CenterPanel);
            app.KontrastSlider_2Label.Text = {'Kontrast'; ''};
            app.KontrastSlider_2 = uislider(app.CenterPanel);
            app.KontrastSlider_2.Limits = [0 1];
            app.KontrastSlider_2.MajorTicks = [];
            app.KontrastSlider_2.ValueChangingFcn = createCallbackFcn(app, @KontrastSlider_2ValueChanging, true);
            app.KontrastSlider_2.MinorTicks = [];
            app.KontrastSlider_2.Value = 0.5;
            app.JasnoSlider_2Label = uilabel(app.CenterPanel);
            app.JasnoSlider_2Label.Text = {'Jasność'; ''; ''};
            app.Right = uiimage(app.CenterPanel);
            app.Right.ImageClickedFcn = createCallbackFcn(app, @RightImageClicked, true);
            app.Right.ImageSource = 'next-button.png';
            app.Left = uiimage(app.CenterPanel);
            app.Left.ImageClickedFcn = createCallbackFcn(app, @LeftImageClicked, true);
            app.Left.ImageSource = 'previous.png';
            app.JasnoSlider_2 = uislider(app.CenterPanel);
            app.JasnoSlider_2.Limits = [-1 1];
            app.JasnoSlider_2.MajorTicks = [];
            app.JasnoSlider_2.ValueChangingFcn = createCallbackFcn(app, @JasnoSlider_2ValueChanging, true);
            app.JasnoSlider_2.MinorTicks = [];
            app.Number_image = uieditfield(app.CenterPanel, 'numeric');
            app.Number_image.ValueChangedFcn = createCallbackFcn(app, @Number_imageValueChanged, true);
            app.SerieDropDownLabel = uilabel(app.CenterPanel);
            app.SerieDropDownLabel.Text = 'Serie';
            app.SerieDropDown = uidropdown(app.CenterPanel);
            app.SerieDropDown.Items = {};
            app.SerieDropDown.ValueChangedFcn = createCallbackFcn(app, @SerieDropDownValueChanged, true);
            app.SerieDropDown.Value = {};
            app.SerieDropDown_2Label = uilabel(app.CenterPanel);
            app.SerieDropDown_2Label.Text = 'Serie';
            app.SerieDropDown_2 = uidropdown(app.CenterPanel);
            app.SerieDropDown_2.Items = {};
            app.SerieDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SerieDropDown_2ValueChanged, true);
            app.SerieDropDown_2.Value = {};
            app.KontrastSlider_2Label_2 = uilabel(app.CenterPanel);
            app.KontrastSlider_2Label_2.Text = {'Kontrast'; ''};
            app.KontrastSlider_4 = uislider(app.CenterPanel);
            app.KontrastSlider_4.Limits = [0 1];
            app.KontrastSlider_4.MajorTicks = [];
            app.KontrastSlider_4.ValueChangingFcn = createCallbackFcn(app, @KontrastSlider_4ValueChanging, true);
            app.KontrastSlider_4.MinorTicks = [];
            app.KontrastSlider_4.Value = 0.5;
            app.JasnoSlider_3Label = uilabel(app.CenterPanel);
            app.JasnoSlider_3Label.Text = {'Jasność'; ''; ''};
            app.JasnoSlider_3 = uislider(app.CenterPanel);
            app.JasnoSlider_3.Limits = [-1 1];
            app.JasnoSlider_3.MajorTicks = [];
            app.JasnoSlider_3.ValueChangingFcn = createCallbackFcn(app, @JasnoSlider_3ValueChanging, true);
            app.JasnoSlider_3.MinorTicks = [];
            app.ZapiszButton = uibutton(app.CenterPanel, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Text = 'Zapisz';
            app.OpisbadaniaLabel = uilabel(app.CenterPanel);
            app.OpisbadaniaLabel.Text = 'Opis badania:';
            app.OpisbadaniaTextArea = uitextarea(app.CenterPanel);
            app.UIAxes = uiaxes(app.CenterPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Enable = 'off';
            % Show the figure after all components are created
            app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = okno_glowne

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SystemkomputerowegowspomaganiadiagnostykimedycznejUIFigure)
        end
    end
end